classdef GUICifrados_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        uipanel1                    matlab.ui.container.Panel
        EditField_3                 matlab.ui.control.EditField
        LimpiarButton               matlab.ui.control.Button
        EditField_2                 matlab.ui.control.EditField
        HistogramaESINButton        matlab.ui.control.Button
        EditField                   matlab.ui.control.NumericEditField
        TipodeoperacionSwitch       matlab.ui.control.Switch
        TipodeoperacionSwitchLabel  matlab.ui.control.Label
        TextArea                    matlab.ui.control.TextArea
        text3                       matlab.ui.control.Label
        popupmenu1                  matlab.ui.control.DropDown
        text2                       matlab.ui.control.Label
        pushbutton1                 matlab.ui.control.Button
        axes1                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        alfabet = ["a" "b" "c" "d" "e" "f" "g" "h" "i" "j" "k" "l" "m" "n" "ñ" "o" "p" "q" "r" "s" "t" "u" "v" "w" "x" "y" "z"]; %ALFABETO PARA EL CIFRADO CESAR 
        CountAlpha = 0; % VARIABLE PARA ALMACENAR LA POSICION DEL ALFABETO
        Tp="";% VARIABLE PARA EL TEXTO PLANO
        temp = ""; %TEMPORAL PARA GUARDAR LOS CARACTERES DE LA CADENA INGRESADA POR EL USUARIO
        Tc = ""; % VARIABLE PARA EL TEXTO CIFRADO
        frecuencyTP = [0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0];
        frecuencyTC = [0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0];
        frecuency = [12.53,1.42,4.68,5.86,13.68,0.69,1.01,0.70,6.25,0.44,0.02,4.97,3.15,6.71,0.31,8.68,2.51,0.88,6.87,7.98,4.63,3.93,0.90,0.01,0.22,0.90,0.52];%Vector de frecuencias español
        frecuency2= [8.34,1.54,2.73,4.14,12.60,2.03,1.92,6.11,6.71,0.23,0.87,4.24,2.53,6.80,0,7.70,1.66,0.09,5.68,6.11,9.37,2.85,1.06,2.34,0.20,2.04,0.06];%Vector de frecuencias ingles
        KeyVi=[];%Vector vacio para la cantidad de veces que aparece una letra
        PosKey=1;%Contador
        FinalKey="";
        Newdiccionario="";
        plaFairDic=["a" "b" "c" "d" "e" "f" "g" "h" "i" "k" "l" "m" "n" "o" "p" "q" "r" "s" "t" "u" "v" "w" "x" "y" "z"];
        neutro="";
        MatixPlay=[];
        Normalize="";
        M1="";
        M2="";
        C1="";
        C2="";
        frecuencyTPPlay = [0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0];
        frecuencyTCPlay = [0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0];
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUICifrados_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GUICifrados (see VARARGIN)
            
            % Choose default command line output for GUICifrados
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

                val=app.popupmenu1.Value;
                switch(val)
                    case "Cesar"
                        if app.TipodeoperacionSwitch.Value == "Cifrado"
                                    for i= 1:length(app.frecuencyTC)%Ciclo for par limpiar el vector de frecuencia
                                        app.frecuencyTC(i)=0;%Igualando a cero los valores
                                    end
                                    app.Tc = ""; %LIMPIAR VARIABLE
                                    app.Tp= app.TextArea.Value; % CONSULTA AL USUARIO PARA LA OBTENER EL TEXTO PLANO
                                    Key = app.EditField.Value; % CONSULTA AL USUARIO PARA EL TAMAÑO DE LA LLAVE
                                    app.Tp = lower(app.Tp); % CONVERSION DE LA CADENA EN MINUSCULAS
                                    app.Tp = strrep(strrep(strrep(strrep(strrep(strrep(app.Tp, "á", "a"), "é", "e"), "í", "i"), "ó", "o"), "ú", "u"),"ü","u"); % USO DEL METODO STRREP PARA REMPLAZAR LAS VOCALES CON ACENTO
                                    app.Tp = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(app.Tp, "0", "cero"), "1", "uno"), "2", "dos"), "3", "tres"), "4", "cuatro"), "5", "cinco"), "6", "seis"), "7", "siete"), "8", "ocho"), "9", "nueve"); % USO DEL METODO STRREP PARA REMPLAZAR NUMERO PARA SU CONTRA PARTE ALFABETICA
                                    app.Tp = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(app.Tp, "!", ""), "¡", ""), "?", ""), "¿", ""), "(", ""), ")", ""), ";", ""), ",", ""), ".", ""), ":", ""), "-", ""), "_", ""), "<", ""), ">", ""), "..", ""), "...", ""), "/", ""), "}", ""), "{", ""), "[", ""), "]", ""), "+", ""), "*", ""), "$", ""), "%", ""), "&", ""), "=", ""), "|", ""); % USO DEL METODO STRREP PARA REMPLAZAR SIGNOS
                                    app.Tp = strtrim(app.Tp); %LIMPIAR ESPACIOS AL INICIO Y AL FINAL
                                    a = strlength(app.Tp); % VARIABLE PARA ALMACENAR EL TAMAÑO DEL TEXTO PLANO
                                    if a==0
                                        app.TextArea.Value="No hay texto para cifrar";
                                        return
                                    end
                                    if Key == 1 || Key >= length(app.alfabet)
                                        app.TextArea.Value="La llave no puede ser igual a 1 o al tamaño del diccionario";
                                        return
                                    end
                                    for index = 1:a % CICLO FOR PARA CIFRAR EL TEXTO PLANO CON CON UN RANGO DE 1 AL TAMAÑO DEL TEXTO PLANO
                                        app.temp = strcat(app.temp, extract(app.Tp,index)); % SUSTRACCION DE CARACTERES DEL TEXTO PLANO POR POSICION
                                        if find(app.alfabet == app.temp) % IF PARA VALIDAR QUE EL CARACTER ESTE EN EL ALFABETO
                                            app.CountAlpha = find(app.alfabet == app.temp); % BUSQUEDA DEL CARACTER EN EL ALFABETO PARA GUARDAR SU INDICE
                                            newindex = app.CountAlpha + Key; % VARIABLE PARA ASIGNAR EL NUEVO INDICE SEGUN EL CIFRADO CESAR ES IGUAL A LA POSICION ACTUAL DEL CARACTER MÁS LA LLAVE
                                            if newindex > length(app.alfabet) %IF PARA VALIDAR SI EL TAMAÑO DEL NUEVO INDICE ES MAYOR AL TAMAÑO DEL ALFABETO SI ES ASI ENTRE AL IF
                                                newindex = mod(newindex, length(app.alfabet)); % SI EL NUEVO INDICE ES MAYOR SE APLICA EL MODULO DEL INDICE CON RESPECTO AL TAMAÑO DEL ALFABETO PARA OBTENER LA NUEVA POSICION
                                                if newindex == 0 % SI EL NUEVO INDICE ES CERO AIGNA EL TAMAÑO DEL ALFABETO
                                                    newindex = length(app.alfabet); % SE LE ASIGNA AL NUEVO INDICE EL TAMAÑO DEL ALFABETO
                                                end % FIN DEL IF
                                            end % FIN DEL IF
                                                app.Tc = strcat(app.Tc, strrep(app.temp, app.alfabet(app.CountAlpha), app.alfabet(newindex))); %SE REMPLAZA EL CARACTER ANTERIOR CON EL NUEVO Y SE CONCATENA PARA OBTENER EL TEXTO CIFRADO
                                                 app.frecuencyTP(newindex)= app.frecuencyTP(newindex)+1;%Aumentando el contador de la variable 
                                        end % FIN DEL IF
                                                app.temp = ""; % SE RESETEA LA VARIABLE TEMPORAL
                                    end % FIN DEL FOR
                                        for i= 1:length(app.frecuencyTP)%Ciclo for para sacar las frecuencias de los caracteres
                                            b = app.frecuencyTP(i);%Igualando b al valor actual de app.frecuencyTP(i)
                                            c= b/a;%Dividiendo el valor actual entre el tamaño del texto 
                                            app.frecuencyTC(i)=c*100;%Multiplicando  app.frecuencyTC(i) por 100
                                        end
                                    app.TextArea.Value=app.Tc;% SE IMPRIME EL TEXTO CIFRADO
                                    app.Tp = ""; %LIMPIAR VARIABLE
                                    alfabet2 = categorical({'a' 'b' 'c' 'd' 'e' 'f' 'g' 'h' 'i' 'j' 'k' 'l' 'm' 'n' 'ñ' 'o' 'p' 'q' 'r' 's' 't' 'u' 'v' 'w' 'x' 'y' 'z'});%Vector del alfabeto para el histograma
                                    c2=[app.frecuency;app.frecuencyTC;app.frecuency2];%VMatriz de las frecuencias
                                    bar(app.axes1,alfabet2,c2);%Funcion para crear el histograma
                                    legend(app.axes1,{'RAE';'Texto cifrado';'Ingles'});%Legendas para el histograma
                                    title(app.axes1,"Histograma de Frecuencias texto cifrado");%Titulo para el histograma
                                    for i= 1:length(app.frecuencyTP)%Ciclo for par limpiar el vector de frecuencia
                                        app.frecuencyTP(i)=0;%Igualando a cero los valores
                                    end
                                    
                            else
                                        for i= 1:length(app.frecuencyTC)%Ciclo for par limpiar el vector de frecuencia
                                            app.frecuencyTC(i)=0;
                                        end
                                         app.Tp = ""; %LIMPIAR VARIABLE
                                         app.Tc = app.TextArea.Value; % CONSULTA AL USUARIO PARA LA OBTENER EL TEXTO PLANO
                                         Key = app.EditField.Value; % CONSULTA AL USUARIO PARA EL TAMAÑO DE LA LLAVE
                                         app.Tc = lower( app.Tc); % CONVERSION DE LA CADENA EN MINUSCULAS
                                         app.Tc = strrep(strrep(strrep(strrep(strrep(strrep(app.Tc, "á", "a"), "é", "e"), "í", "i"), "ó", "o"), "ú", "u"),"ü","u"); % USO DEL METODO STRREP PARA REMPLAZAR LAS VOCALES CON ACENTO
                                         app.Tc = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep( app.Tc, "0", "cero"), "1", "uno"), "2", "dos"), "3", "tres"), "4", "cuatro"), "5", "cinco"), "6", "seis"), "7", "siete"), "8", "ocho"), "9", "nueve"); % USO DEL METODO STRREP PARA REMPLAZAR NUMERO PARA SU CONTRA PARTE ALFABETICA
                                         app.Tc = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep( app.Tc, "!", ""), "¡", ""), "?", ""), "¿", ""), "(", ""), ")", ""), ";", ""), ",", ""), ".", ""), ":", ""), "-", ""), "_", ""), "<", ""), ">", ""), "..", ""), "...", ""), "/", ""), "}", ""), "{", ""), "[", ""), "]", ""), "+", ""), "*", ""), "$", ""), "%", ""), "&", ""), "=", ""), "|", ""); % USO DEL METODO STRREP PARA REMPLAZAR SIGNOS
                                         app.Tc = strtrim( app.Tc); %LIMPIAR ESPACIOS AL INICIO Y AL FINAL
                                         a =  strlength(app.Tc); % VARIABLE PARA ALMACENAR EL TAMAÑO DEL TEXTO PLANO
                                         if a==0
                                                app.TextArea.Value="No hay texto para descifrar";
                                                return
                                         end
                                            for index = 1:a % CICLO FOR PARA CIFRAR EL TEXTO PLANO CON CON UN RANGO DE 1 AL TAMAÑO DEL TEXTO PLANO
                                                 app.temp = strcat( app.temp,  extract(app.Tc,index)); % SUSTRACCION DE CARACTERES DEL TEXTO PLANO POR POSICION
                                                if find( app.alfabet ==  app.temp) % IF PARA VALIDAR QUE EL CARACTER ESTE EN EL ALFABETO
                                                     app.CountAlpha = find( app.alfabet ==  app.temp); % BUSQUEDA DEL CARACTER EN EL ALFABETO PARA GUARDAR SU INDICE
                                                    newindex =  app.CountAlpha - Key; % VARIABLE PARA ASIGNAR EL NUEVO INDICE SEGUN EL CIFRADO CESAR ES IGUAL A LA POSICION ACTUAL DEL CARACTER MENOS LA LLAVE
                                                    if newindex < 1 %IF PARA VALIDAR SI EL TAMAÑO DEL NUEVO INDICE ES MENOR A 1 SI ES ASI ENTRA AL IF
                                                        newindex = mod(newindex, length( app.alfabet)); % SI EL NUEVO INDICE ES MENOR SE APLICA EL MODULO DEL INDICE CON RESPECTO AL TAMAÑO DEL ALFABETO PARA OBTENER LA NUEVA POSICION
                                                        if newindex == 0 % SI EL NUEVO INDICE ES CERO AIGNA EL TAMAÑO DEL ALFABETO
                                                            newindex = length( app.alfabet); % SE LE ASIGNA AL NUEVO INDICE EL TAMAÑO DEL ALFABETO
                                                        end % FIN DEL IF
                                                    end % FIN DEL IF
                                                         app.Tp = strcat( app.Tp, strrep( app.temp,  app.alfabet( app.CountAlpha),  app.alfabet(newindex))); %SE REMPLAZA EL CARACTER ANTERIOR CON EL NUEVO Y SE CONCATENA PARA OBTENER EL TEXTO CIFRADO
                                                         app.frecuencyTC(newindex)= app.frecuencyTC(newindex)+1;%Aumentando el contador de la variable 
                                                end % FIN DEL IF
                                                         app.temp = ""; % SE RESETEA LA VARIABLE TEMPORAL
                                            end % FIN DEL FOR
                                            for i= 1:length(app.frecuencyTC)%Ciclo for para sacar las frecuencias de los caracteres
                                                    b = app.frecuencyTC(i);%Igualando b al valor actual de app.frecuencyTC(i)
                                                    c= b/a;%Dividiendo el valor actual entre el tamaño del texto 
                                                    app.frecuencyTP(i)=c*100;%Multiplicando  app.frecuencyTP(i) por 100
                                            end
                                            app.TextArea.Value=app.Tp;% SE IMPRIME EL TEXTO CIFRADO
                                            alfabet2 = categorical({'a' 'b' 'c' 'd' 'e' 'f' 'g' 'h' 'i' 'j' 'k' 'l' 'm' 'n' 'ñ' 'o' 'p' 'q' 'r' 's' 't' 'u' 'v' 'w' 'x' 'y' 'z'});%Vector del alfabeto para el histograma
                                            c2=[app.frecuency;app.frecuencyTP;app.frecuency2];%VMatriz de las frecuencias
                                            bar(app.axes1,alfabet2,c2);%Funcion para crear el histograma
                                            legend(app.axes1,{'RAE';'Texto descifrado';'Ingles'});%Legendas para el histograma
                                            title(app.axes1,"Histograma de Frecuencias texto descifrado");%Titulo para el histograma
                                            for i= 1:length(app.frecuencyTP)%Ciclo for par limpiar el vector de frecuencia
                                                app.frecuencyTP(i)=0;%Igualando a cero los valores
                                            end
                                            
                        end
                  case "Vigenere"
                            if app.TipodeoperacionSwitch.Value == "Cifrado" 
                                    for i= 1:length(app.frecuencyTC)%Ciclo for par limpiar el vector de frecuencia
                                        app.frecuencyTC(i)=0;%Igualando a cero los valores
                                    end% FIN DEL FOR
                                    app.KeyVi=[];%LIMPIAR VARIABLE
                                    app.PosKey = 1;%LIMPIAR VARIABLE
                                    app.Tc = ""; %LIMPIAR VARIABLE
                                    app.Tp= app.TextArea.Value; % CONSULTA AL USUARIO PARA LA OBTENER EL TEXTO PLANO
                                    Key = app.EditField_2.Value; % CONSULTA AL USUARIO PARA EL TAMAÑO DE LA LLAVE
                                    app.Tp = lower(app.Tp); % CONVERSION DE LA CADENA EN MINUSCULAS
                                    app.Tp = strrep(strrep(strrep(strrep(strrep(strrep(app.Tp, "á", "a"), "é", "e"), "í", "i"), "ó", "o"), "ú", "u"),"ü","u"); % USO DEL METODO STRREP PARA REMPLAZAR LAS VOCALES CON ACENTO
                                    app.Tp = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(app.Tp, "0", "cero"), "1", "uno"), "2", "dos"), "3", "tres"), "4", "cuatro"), "5", "cinco"), "6", "seis"), "7", "siete"), "8", "ocho"), "9", "nueve"); % USO DEL METODO STRREP PARA REMPLAZAR NUMERO PARA SU CONTRA PARTE ALFABETICA
                                    app.Tp = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(app.Tp, "!", ""), "¡", ""), "?", ""), "¿", ""), "(", ""), ")", ""), ";", ""), ",", ""), ".", ""), ":", ""), "-", ""), "_", ""), "<", ""), ">", ""), "..", ""), "...", ""), "/", ""), "}", ""), "{", ""), "[", ""), "]", ""), "+", ""), "*", ""), "$", ""), "%", ""), "&", ""), "=", ""), "|", "")," ",""),"\r\n",""),"\n",""),"\r",""); % USO DEL METODO STRREP PARA REMPLAZAR SIGNOS
                                    app.Tp = strtrim(app.Tp); %LIMPIAR ESPACIOS AL INICIO Y AL FINAL
                                    Key = lower(Key); % CONVERSION DE LA CADENA EN MINUSCULAS
                                    Key = strrep(strrep(strrep(strrep(strrep(strrep(Key, "á", "a"), "é", "e"), "í", "i"), "ó", "o"), "ú", "u"),"ü","u"); % USO DEL METODO STRREP PARA REMPLAZAR LAS VOCALES CON ACENTO
                                    Key = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(Key, "0", "cero"), "1", "uno"), "2", "dos"), "3", "tres"), "4", "cuatro"), "5", "cinco"), "6", "seis"), "7", "siete"), "8", "ocho"), "9", "nueve"); % USO DEL METODO STRREP PARA REMPLAZAR NUMERO PARA SU CONTRA PARTE ALFABETICA
                                    Key = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(Key, "!", ""), "¡", ""), "?", ""), "¿", ""), "(", ""), ")", ""), ";", ""), ",", ""), ".", ""), ":", ""), "-", ""), "_", ""), "<", ""), ">", ""), "..", ""), "...", ""), "/", ""), "}", ""), "{", ""), "[", ""), "]", ""), "+", ""), "*", ""), "$", ""), "%", ""), "&", ""), "=", ""), "|", "")," ",""); % USO DEL METODO STRREP PARA REMPLAZAR SIGNOS
                                    Key = strtrim(Key); %LIMPIAR ESPACIOS AL INICIO Y AL FINAL
                                    a = strlength(app.Tp); % VARIABLE PARA ALMACENAR EL TAMAÑO DEL TEXTO PLANO
                                    if a==0
                                       app.TextArea.Value="No hay texto para cifrar";
                                       return
                                    end
                                    b= strlength(Key);%VARIABLE PARA ALMACENAR EL TAMAÑO DE LA LLAVE
                                    if b==0
                                       app.TextArea.Value="La llave no puede estar vacia";
                                       return
                                    end
                                    for i = 1:b%FOR PARA OBTENER LAS POCISIONES EN EL ALFABETO DE LA LLAVE
                                       app.temp = strcat(app.temp, extract(Key,i)); % SUSTRACCION DE CARACTERES DE LA LLAVE POR POSICION
                                        c = find(app.alfabet == app.temp); %BUSQUEDA DEL CARACTER EN EL ALFABETO PARA GUARDAR SU INDICE
                                        app.KeyVi(i)=c;%SE AGREGA AL VECTOR LA POSCISION DEL CARACTER
                                        app.temp="";%LIMPIAR VARIABLE
                                    end% FIN DEL FOR
                                    for index = 1:a % CICLO FOR PARA CIFRAR EL TEXTO PLANO CON CON UN RANGO DE 1 AL TAMAÑO DEL TEXTO PLANO
                                        app.temp = strcat(app.temp, extract(app.Tp,index)); % SUSTRACCION DE CARACTERES DEL TEXTO PLANO POR POSICION
                                        if find(app.alfabet == app.temp) % IF PARA VALIDAR QUE EL CARACTER ESTE EN EL ALFABETO
                                            app.CountAlpha = find(app.alfabet == app.temp); % BUSQUEDA DEL CARACTER EN EL ALFABETO PARA GUARDAR SU INDICE
                                            newindex = app.CountAlpha + app.KeyVi(app.PosKey); % VARIABLE PARA ASIGNAR EL NUEVO INDICE SEGUN EL CIFRADO CESAR ES IGUAL A LA POSICION ACTUAL DEL CARACTER MÁS LA LLAVE
                                            if newindex > length(app.alfabet) %IF PARA VALIDAR SI EL TAMAÑO DEL NUEVO INDICE ES MAYOR AL TAMAÑO DEL ALFABETO SI ES ASI ENTRE AL IF
                                                newindex = mod(newindex, length(app.alfabet)); % SI EL NUEVO INDICE ES MAYOR SE APLICA EL MODULO DEL INDICE CON RESPECTO AL TAMAÑO DEL ALFABETO PARA OBTENER LA NUEVA POSICION
                                                if newindex == 0 % SI EL NUEVO INDICE ES CERO AIGNA EL TAMAÑO DEL ALFABETO
                                                    newindex = length(app.alfabet); % SE LE ASIGNA AL NUEVO INDICE EL TAMAÑO DEL ALFABETO
                                                end % FIN DEL IF
                                            end % FIN DEL IF
                                                app.Tc = strcat(app.Tc, strrep(app.temp, app.alfabet(app.CountAlpha), app.alfabet(newindex))); %SE REMPLAZA EL CARACTER ANTERIOR CON EL NUEVO Y SE CONCATENA PARA OBTENER EL TEXTO CIFRADO
                                                 app.frecuencyTP(newindex)= app.frecuencyTP(newindex)+1;%Aumentando el contador de la variable 
                                        end % FIN DEL IF
                                                app.temp = ""; % SE RESETEA LA VARIABLE TEMPORAL
                                               if app.PosKey <= length(app.KeyVi)%VALIDACION PARA VER QUE EL CONTADOR SEA MENOR AL TAMAÑO DE LA LLAVE
                                                      app.PosKey= app.PosKey + 1;%Aumentando el contador de la variable 
                                                    if app.PosKey > length(app.KeyVi)%VALIDACION PARA QUE EL CONTADOR DE LA LLAVE  SE REINICIE
                                                            app.PosKey = 1;%Igualando a uno el contador   
                                                     end% FIN DEL IF
                                               end% FIN DEL IF
                                    end % FIN DEL FOR
                                        for i= 1:length(app.frecuencyTP)%Ciclo for para sacar las frecuencias de los caracteres
                                            b = app.frecuencyTP(i);%Igualando b al valor actual de app.frecuencyTP(i)
                                            c= b/a;%Dividiendo el valor actual entre el tamaño del texto 
                                            app.frecuencyTC(i)=c*100;%Multiplicando  app.frecuencyTC(i) por 100
                                        end% FIN DEL FOR
                                    app.TextArea.Value=app.Tc;% SE IMPRIME EL TEXTO CIFRADO
                                    app.Tp = ""; %LIMPIAR VARIABLE
                                    alfabet2 = categorical({'a' 'b' 'c' 'd' 'e' 'f' 'g' 'h' 'i' 'j' 'k' 'l' 'm' 'n' 'ñ' 'o' 'p' 'q' 'r' 's' 't' 'u' 'v' 'w' 'x' 'y' 'z'});%Vector del alfabeto para el histograma
                                    c2=[app.frecuency;app.frecuencyTC;app.frecuency2];%VMatriz de las frecuencias
                                    bar(app.axes1,alfabet2,c2);%Funcion para crear el histograma
                                    legend(app.axes1,{'RAE';'Texto cifrado';'Ingles'});%Legendas para el histograma
                                    title(app.axes1,"Histograma de Frecuencias texto cifrado");%Titulo para el histograma
                                    for i= 1:length(app.frecuencyTP)%Ciclo for par limpiar el vector de frecuencia
                                        app.frecuencyTP(i)=0;%Igualando a cero los valores
                                    end% FIN DEL FOR
                            else
                                        for i= 1:length(app.frecuencyTC)%Ciclo for par limpiar el vector de frecuencia
                                            app.frecuencyTC(i)=0;%Igualando a cero los valores
                                        end% FIN DEL FOR
                                         app.KeyVi=[];%LIMPIAR VARIABLE
                                         app.PosKey = 1;%LIMPIAR VARIABLE
                                         app.Tp = ""; %LIMPIAR VARIABLE
                                         app.Tc = app.TextArea.Value; % CONSULTA AL USUARIO PARA LA OBTENER EL TEXTO PLANO
                                         Key = app.EditField_2.Value; % CONSULTA AL USUARIO PARA EL TAMAÑO DE LA LLAVE
                                         app.Tc = lower( app.Tc); % CONVERSION DE LA CADENA EN MINUSCULAS
                                         app.Tc = strrep(strrep(strrep(strrep(strrep(strrep(app.Tc, "á", "a"), "é", "e"), "í", "i"), "ó", "o"), "ú", "u"),"ü","u"); % USO DEL METODO STRREP PARA REMPLAZAR LAS VOCALES CON ACENTO
                                         app.Tc = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep( app.Tc, "0", "cero"), "1", "uno"), "2", "dos"), "3", "tres"), "4", "cuatro"), "5", "cinco"), "6", "seis"), "7", "siete"), "8", "ocho"), "9", "nueve"); % USO DEL METODO STRREP PARA REMPLAZAR NUMERO PARA SU CONTRA PARTE ALFABETICA
                                         app.Tc = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(app.Tc, "!", ""), "¡", ""), "?", ""), "¿", ""), "(", ""), ")", ""), ";", ""), ",", ""), ".", ""), ":", ""), "-", ""), "_", ""), "<", ""), ">", ""), "..", ""), "...", ""), "/", ""), "}", ""), "{", ""), "[", ""), "]", ""), "+", ""), "*", ""), "$", ""), "%", ""), "&", ""), "=", ""), "|", "")," ",""); % USO DEL METODO STRREP PARA REMPLAZAR SIGNOS
                                         app.Tc = strtrim( app.Tc); %LIMPIAR ESPACIOS AL INICIO Y AL FINAL
                                         Key = lower(Key); % CONVERSION DE LA CADENA EN MINUSCULAS
                                         Key = strrep(strrep(strrep(strrep(strrep(strrep(Key, "á", "a"), "é", "e"), "í", "i"), "ó", "o"), "ú", "u"),"ü","u"); % USO DEL METODO STRREP PARA REMPLAZAR LAS VOCALES CON ACENTO
                                         Key = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(Key, "0", "cero"), "1", "uno"), "2", "dos"), "3", "tres"), "4", "cuatro"), "5", "cinco"), "6", "seis"), "7", "siete"), "8", "ocho"), "9", "nueve"); % USO DEL METODO STRREP PARA REMPLAZAR NUMERO PARA SU CONTRA PARTE ALFABETICA
                                         Key = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(Key, "!", ""), "¡", ""), "?", ""), "¿", ""), "(", ""), ")", ""), ";", ""), ",", ""), ".", ""), ":", ""), "-", ""), "_", ""), "<", ""), ">", ""), "..", ""), "...", ""), "/", ""), "}", ""), "{", ""), "[", ""), "]", ""), "+", ""), "*", ""), "$", ""), "%", ""), "&", ""), "=", ""), "|", "")," ",""); % USO DEL METODO STRREP PARA REMPLAZAR SIGNOS
                                         Key = strtrim(Key); %LIMPIAR ESPACIOS AL INICIO Y AL FINAL
                                         a =  strlength(app.Tc); % VARIABLE PARA ALMACENAR EL TAMAÑO DEL TEXTO PLANO
                                         if a==0
                                            app.TextArea.Value="No hay texto para descifrar";
                                            return
                                         end
                                         b= strlength(Key);%VARIABLE PARA ALMACENAR EL TAMAÑO DE LA LLAVE
                                         if b==0
                                             app.TextArea.Value="La llave no puede estar vacia";
                                             return
                                         end
                                        for i = 1:b%FOR PARA OBTENER LAS POCISIONES EN EL ALFABETO DE LA LLAVE
                                           app.temp = strcat(app.temp, extract(Key,i)); % SUSTRACCION DE CARACTERES DE LA LLAVE POR POSICION
                                            c = find(app.alfabet == app.temp); %BUSQUEDA DEL CARACTER EN EL ALFABETO PARA GUARDAR SU INDICE
                                            app.KeyVi(i)=c;%SE AGREGA AL VECTOR LA POSCISION DEL CARACTER
                                            app.temp="";%LIMPIAR VARIABLE
                                        end% FIN DEL FOR
                                            for index = 1:a % CICLO FOR PARA CIFRAR EL TEXTO PLANO CON CON UN RANGO DE 1 AL TAMAÑO DEL TEXTO PLANO
                                                 app.temp = strcat( app.temp,  extract(app.Tc,index)); % SUSTRACCION DE CARACTERES DEL TEXTO PLANO POR POSICION
                                                if find( app.alfabet ==  app.temp) % IF PARA VALIDAR QUE EL CARACTER ESTE EN EL ALFABETO
                                                     app.CountAlpha = find( app.alfabet ==  app.temp); % BUSQUEDA DEL CARACTER EN EL ALFABETO PARA GUARDAR SU INDICE
                                                    newindex = app.CountAlpha - app.KeyVi(app.PosKey); % VARIABLE PARA ASIGNAR EL NUEVO INDICE SEGUN EL CIFRADO CESAR ES IGUAL A LA POSICION ACTUAL DEL CARACTER MENOS LA LLAVE
                                                    if newindex < 1 %IF PARA VALIDAR SI EL TAMAÑO DEL NUEVO INDICE ES MENOR A 1 SI ES ASI ENTRA AL IF
                                                        newindex = mod(newindex, length( app.alfabet)); % SI EL NUEVO INDICE ES MENOR SE APLICA EL MODULO DEL INDICE CON RESPECTO AL TAMAÑO DEL ALFABETO PARA OBTENER LA NUEVA POSICION
                                                        if newindex == 0 % SI EL NUEVO INDICE ES CERO AIGNA EL TAMAÑO DEL ALFABETO
                                                            newindex = length( app.alfabet); % SE LE ASIGNA AL NUEVO INDICE EL TAMAÑO DEL ALFABETO
                                                        end % FIN DEL IF
                                                    end % FIN DEL IF
                                                         app.Tp = strcat( app.Tp, strrep( app.temp,  app.alfabet( app.CountAlpha),  app.alfabet(newindex))); %SE REMPLAZA EL CARACTER ANTERIOR CON EL NUEVO Y SE CONCATENA PARA OBTENER EL TEXTO CIFRADO
                                                         app.frecuencyTC(newindex)= app.frecuencyTC(newindex)+1;%Aumentando el contador de la variable 
                                                end % FIN DEL IF
                                                         app.temp = ""; % SE RESETEA LA VARIABLE TEMPORAL
                                                         if app.PosKey <= length(app.KeyVi)%VALIDACION PARA VER QUE EL CONTADOR SEA MENOR AL TAMAÑO DE LA LLAVE
                                                                app.PosKey= app.PosKey + 1;%Aumentando el contador de la variable 
                                                            if app.PosKey > length(app.KeyVi)%VALIDACION PARA QUE EL CONTADOR DE LA LLAVE  SE REINICIE
                                                                    app.PosKey = 1;%Igualando a uno el contador  
                                                            end% FIN DEL IF
                                                         end% FIN DEL IF
                                            end % FIN DEL FOR
                                            for i= 1:length(app.frecuencyTC)%Ciclo for para sacar las frecuencias de los caracteres
                                                    b = app.frecuencyTC(i);%Igualando b al valor actual de app.frecuencyTC(i)
                                                    c= b/a;%Dividiendo el valor actual entre el tamaño del texto 
                                                    app.frecuencyTP(i)=c*100;%Multiplicando  app.frecuencyTC(i) por 100
                                            end% FIN DEL FOR
                                            app.TextArea.Value=app.Tp;% SE IMPRIME EL TEXTO CIFRADO
                                            alfabet2 = categorical({'a' 'b' 'c' 'd' 'e' 'f' 'g' 'h' 'i' 'j' 'k' 'l' 'm' 'n' 'ñ' 'o' 'p' 'q' 'r' 's' 't' 'u' 'v' 'w' 'x' 'y' 'z'});%Vector del alfabeto para el histograma
                                            c2=[app.frecuency;app.frecuencyTP;app.frecuency2];%VMatriz de las frecuencias
                                            bar(app.axes1,alfabet2,c2);%Funcion para crear el histograma
                                            legend(app.axes1,{'RAE';'Texto descifrado';'Ingles'});%Legendas para el histograma
                                            title(app.axes1,"Histograma de Frecuencias texto descifrado");%Titulo para el histograma
                                            for i= 1:length(app.frecuencyTP)%Ciclo for par limpiar el vector de frecuencia
                                                app.frecuencyTP(i)=0;%Igualando a cero los valores
                                            end% FIN DEL FOR
                            end% FIN DEL IF
                    case "Playfair"
                            if app.TipodeoperacionSwitch.Value == "Cifrado" 
                                    for i= 1:length(app.frecuencyTCPlay)%Ciclo for par limpiar el vector de frecuencia
                                        app.frecuencyTCPlay(i)=0;%Igualando a cero los valores
                                    end% FIN DEL FOR
                                    app.Normalize="";
                                    app.FinalKey="";
                                    app.Newdiccionario="";
                                    app.Tc = ""; %LIMPIAR VARIABLE
                                    app.Tp= app.TextArea.Value; % CONSULTA AL USUARIO PARA LA OBTENER EL TEXTO PLANO
                                    Key = app.EditField_2.Value; % CONSULTA AL USUARIO PARA EL TAMAÑO DE LA LLAVE
                                    app.neutro = app.EditField_3.Value; % CONSULTA AL USUARIO PARA EL TAMAÑO DE LA LLAVE
                                    app.neutro =lower(app.neutro);
                                    app.Tp = lower(app.Tp); % CONVERSION DE LA CADENA EN MINUSCULAS
                                    app.Tp = strrep(strrep(strrep(strrep(strrep(strrep(app.Tp, "á", "a"), "é", "e"), "í", "i"), "ó", "o"), "ú", "u"),"ü","u"); % USO DEL METODO STRREP PARA REMPLAZAR LAS VOCALES CON ACENTO
                                    app.Tp = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(app.Tp, "0", "cero"), "1", "uno"), "2", "dos"), "3", "tres"), "4", "cuatro"), "5", "cinco"), "6", "seis"), "7", "siete"), "8", "ocho"), "9", "nueve"); % USO DEL METODO STRREP PARA REMPLAZAR NUMERO PARA SU CONTRA PARTE ALFABETICA
                                    app.Tp = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(app.Tp, "!", ""), "¡", ""), "?", ""), "¿", ""), "(", ""), ")", ""), ";", ""), ",", ""), ".", ""), ":", ""), "-", ""), "_", ""), "<", ""), ">", ""), "..", ""), "...", ""), "/", ""), "}", ""), "{", ""), "[", ""), "]", ""), "+", ""), "*", ""), "$", ""), "%", ""), "&", ""), "=", ""), "|", "")," ",""),"\r\n",""),"\n",""),"\r",""); % USO DEL METODO STRREP PARA REMPLAZAR SIGNOS
                                    app.Tp =strrep(strrep(app.Tp, "j", "i"),"ñ","n");
                                    app.Tp=strrep(strrep(app.Tp,"‘",""),"’","");
                                    app.Tp=strrep(app.Tp,"'","");
                                    app.Tp = strtrim(app.Tp); %LIMPIAR ESPACIOS AL INICIO Y AL FINAL
                                    Key = lower(Key); % CONVERSION DE LA CADENA EN MINUSCULAS
                                    Key = strrep(strrep(strrep(strrep(strrep(strrep(Key, "á", "a"), "é", "e"), "í", "i"), "ó", "o"), "ú", "u"),"ü","u"); % USO DEL METODO STRREP PARA REMPLAZAR LAS VOCALES CON ACENTO
                                    Key = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(Key, "0", "cero"), "1", "uno"), "2", "dos"), "3", "tres"), "4", "cuatro"), "5", "cinco"), "6", "seis"), "7", "siete"), "8", "ocho"), "9", "nueve"); % USO DEL METODO STRREP PARA REMPLAZAR NUMERO PARA SU CONTRA PARTE ALFABETICA
                                    Key = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(Key, "!", ""), "¡", ""), "?", ""), "¿", ""), "(", ""), ")", ""), ";", ""), ",", ""), ".", ""), ":", ""), "-", ""), "_", ""), "<", ""), ">", ""), "..", ""), "...", ""), "/", ""), "}", ""), "{", ""), "[", ""), "]", ""), "+", ""), "*", ""), "$", ""), "%", ""), "&", ""), "=", ""), "|", "")," ",""); % USO DEL METODO STRREP PARA REMPLAZAR SIGNOS
                                    Key = strtrim(Key); %LIMPIAR ESPACIOS AL INICIO Y AL FINAL

                                    app.TextArea.Value=app.Tp;
                                    a = strlength(app.Tp); % VARIABLE PARA ALMACENAR EL TAMAÑO DEL TEXTO PLANO
                                    if a==0
                                       app.TextArea.Value="No hay texto para cifrar";
                                       return
                                    end
                                    b= strlength(Key);%VARIABLE PARA ALMACENAR EL TAMAÑO DE LA LLAVE
                                    if b==0
                                       app.TextArea.Value="La llave no puede estar vacia";
                                       return
                                    end
                                    count=1;
                                    count2=1;
                                    temp2="";
                                    j=a;
                                    if mod(a,2) == 1
                                        j= a+1;
                                        a = a+1;
                                        j= j/2;
                                    else
                                        j= j/2;
                                    end
                                    
                                    while count < a
                                        app.temp = strcat(app.temp, extract(app.Tp,count));
                                         if count+1 > strlength(app.Tp) && count+1 == a
                                             temp2 =app.neutro;
                                         else
                                             temp2 = strcat(temp2, extract(app.Tp,count+1));
                                         end
                                          if temp2 == app.temp
                                             temp2= app.neutro;
                                             app.temp = strcat(app.temp,temp2);
                                         else
                                             app.temp = strcat(app.temp,temp2);
                                         end
                                         if temp2 == app.neutro
                                             count= count+1;
                                         else
                                             count= count+2;
                                         end
                                         app.Normalize(count2)=app.temp;
                                         app.temp="";
                                         temp2="";
                                         if count2 <j
                                             count2=count2+1;
                                         end
                                    end
                                    for i = 1:b%FOR PARA OBTENER LAS POCISIONES EN EL ALFABETO DE LA LLAVE
                                        app.temp = strcat(app.temp, extract(Key,i)); % SUSTRACCION DE CARACTERES DE LA LLAVE POR POSICION
                                        app.Newdiccionario(i)=app.temp;%SE AGREGA AL VECTOR LA POSCISION DEL CARACTER
                                        app.temp="";%LIMPIAR VARIABLE
                                   end% FIN DEL FOR
                                    for i = 1:length(app.plaFairDic)%FOR PARA OBTENER LAS POCISIONES EN EL ALFABETO DE LA LLAVE
                                        app.Newdiccionario(i+b)=app.plaFairDic(i);
                                    end% FIN DEL FOR
                                        app.FinalKey= unique(app.Newdiccionario,'stable');
                                        app.MatixPlay = reshape(app.FinalKey,5,5);   
                                        app.MatixPlay = app.MatixPlay.';
                                            z=length(app.Normalize);
                                        for i=1:z
                                            var1= app.Normalize(i);
                                            app.M1=strcat(app.M1,extract(var1,1));
                                            app.M2=strcat(app.M2,extract(var1,2));
                                            [numRows,numCols] = size(app.MatixPlay);
                                            [row,col]= find(app.MatixPlay==app.M1);
                                            [row2,col2]= find(app.MatixPlay==app.M2); 
                                            if row == row2
                                                if col + 1 > numCols
                                                    app.C1 =app.MatixPlay(row,mod(col + 1,numCols));
                                                    app.Tc = strcat( app.Tc,app.C1);
                                                else
                                                    app.C1 =app.MatixPlay(row,col + 1);
                                                    app.Tc = strcat( app.Tc,app.C1);
                                                end
                                                if col2 + 1 > numCols
                                                    app.C2 =app.MatixPlay(row,mod(col2 + 1,numCols));
                                                    app.Tc = strcat( app.Tc,app.C2);
                                                else
                                                    app.C2 =app.MatixPlay(row,col2 + 1);
                                                    app.Tc = strcat( app.Tc,app.C2);
                                                end
                                            elseif col == col2
                                                if row + 1 > numRows
                                                    app.C1 =app.MatixPlay(mod(row + 1,numRows),col);
                                                    app.Tc = strcat( app.Tc,app.C1);
                                                else
                                                    app.C1 =app.MatixPlay(row + 1,col);
                                                    app.Tc = strcat( app.Tc,app.C1);
                                                end
                                                if row2 + 1 > numRows
                                                    app.C2 =app.MatixPlay(mod(row2 + 1,numRows),col2);
                                                    app.Tc = strcat( app.Tc,app.C2);
                                                else
                                                    app.C2 =app.MatixPlay(row2 + 1,col2);
                                                    app.Tc = strcat( app.Tc,app.C2);
                                                end
                                            elseif col~=col2 && row~=row2
                                                  incol=col2;
                                                  incol2=col;
                                                  app.C1 =app.MatixPlay(row,incol);
                                                  app.C2 =app.MatixPlay(row2,incol2);
                                                  app.Tc = strcat( app.Tc,app.C1);
                                                  app.Tc = strcat( app.Tc,app.C2);
                                            end
                                            var1="";
                                            app.M1="";
                                            app.M2="";
                                            app.C1="";
                                            app.C2="";
                                        end
                                        for i=1:strlength(app.Tc)
                                            app.temp=strcat(app.temp,extract(app.Tc,i));
                                            index= find(app.MatixPlay == app.temp);
                                            app.frecuencyTPPlay(index)= app.frecuencyTPPlay(index) + 1;
                                            app.temp="";
                                        end
                                        for i= 1:length(app.frecuencyTPPlay)%Ciclo for para sacar las frecuencias de los caracteres
                                            b = app.frecuencyTPPlay(i);%Igualando b al valor actual de app.frecuencyTP(i)
                                            c= b/a;%Dividiendo el valor actual entre el tamaño del texto 
                                            app.frecuencyTCPlay(i)=c*100;%Multiplicando  app.frecuencyTC(i) por 100
                                        end% FIN DEL FOR
                                    app.TextArea.Value=app.Tc;% SE IMPRIME EL TEXTO CIFRADO
                                    app.Tp = ""; %LIMPIAR VARIABLE
                                    alfabet2 = categorical(app.FinalKey);%Vector del alfabeto para el histograma
                                    %c2=[app.frecuency;app.frecuencyTC;app.frecuency2];%VMatriz de las frecuencias
                                    bar(app.axes1,alfabet2,app.frecuencyTCPlay);%Funcion para crear el histograma
                                    legend(app.axes1,'Texto cifrado');%Legendas para el histograma
                                    title(app.axes1,"Histograma de Frecuencias texto cifrado");%Titulo para el histograma
                                    for i= 1:length(app.frecuencyTPPlay)%Ciclo for par limpiar el vector de frecuencia
                                        app.frecuencyTPPlay(i)=0;%Igualando a cero los valores
                                    end% FIN DEL FOR
                                     
                            else
                                        for i= 1:length(app.frecuencyTCPlay)%Ciclo for par limpiar el vector de frecuencia
                                            app.frecuencyTCPlay(i)=0;%Igualando a cero los valores
                                        end% FIN DEL FOR
                                         app.Normalize="";
                                         app.FinalKey="";
                                         app.Newdiccionario="";
                                         app.Tp = ""; %LIMPIAR VARIABLE
                                         app.Tc = app.TextArea.Value; % CONSULTA AL USUARIO PARA LA OBTENER EL TEXTO PLANO
                                         Key = app.EditField_2.Value; % CONSULTA AL USUARIO PARA EL TAMAÑO DE LA LLAVE
                                         app.neutro = app.EditField_3.Value; % CONSULTA AL USUARIO PARA EL TAMAÑO DE LA LLAVE
                                         app.neutro =lower(app.neutro);
                                         app.Tc = lower( app.Tc); % CONVERSION DE LA CADENA EN MINUSCULAS
                                         app.Tc = strrep(strrep(strrep(strrep(strrep(strrep(app.Tc, "á", "a"), "é", "e"), "í", "i"), "ó", "o"), "ú", "u"),"ü","u"); % USO DEL METODO STRREP PARA REMPLAZAR LAS VOCALES CON ACENTO
                                         app.Tc = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep( app.Tc, "0", "cero"), "1", "uno"), "2", "dos"), "3", "tres"), "4", "cuatro"), "5", "cinco"), "6", "seis"), "7", "siete"), "8", "ocho"), "9", "nueve"); % USO DEL METODO STRREP PARA REMPLAZAR NUMERO PARA SU CONTRA PARTE ALFABETICA
                                         app.Tc = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(app.Tc, "!", ""), "¡", ""), "?", ""), "¿", ""), "(", ""), ")", ""), ";", ""), ",", ""), ".", ""), ":", ""), "-", ""), "_", ""), "<", ""), ">", ""), "..", ""), "...", ""), "/", ""), "}", ""), "{", ""), "[", ""), "]", ""), "+", ""), "*", ""), "$", ""), "%", ""), "&", ""), "=", ""), "|", "")," ",""); % USO DEL METODO STRREP PARA REMPLAZAR SIGNOS
                                         app.Tc =strrep(strrep(app.Tc, "j", "i"),"ñ","n");
                                         app.Tc=strrep(strrep(app.Tc,"‘",""),"’","");
                                         app.Tc=strrep(app.Tc,"'","");
                                         app.Tc = strtrim( app.Tc); %LIMPIAR ESPACIOS AL INICIO Y AL FINAL
                                         Key = lower(Key); % CONVERSION DE LA CADENA EN MINUSCULAS
                                         Key = strrep(strrep(strrep(strrep(strrep(strrep(Key, "á", "a"), "é", "e"), "í", "i"), "ó", "o"), "ú", "u"),"ü","u"); % USO DEL METODO STRREP PARA REMPLAZAR LAS VOCALES CON ACENTO
                                         Key = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(Key, "0", "cero"), "1", "uno"), "2", "dos"), "3", "tres"), "4", "cuatro"), "5", "cinco"), "6", "seis"), "7", "siete"), "8", "ocho"), "9", "nueve"); % USO DEL METODO STRREP PARA REMPLAZAR NUMERO PARA SU CONTRA PARTE ALFABETICA
                                         Key = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(Key, "!", ""), "¡", ""), "?", ""), "¿", ""), "(", ""), ")", ""), ";", ""), ",", ""), ".", ""), ":", ""), "-", ""), "_", ""), "<", ""), ">", ""), "..", ""), "...", ""), "/", ""), "}", ""), "{", ""), "[", ""), "]", ""), "+", ""), "*", ""), "$", ""), "%", ""), "&", ""), "=", ""), "|", "")," ",""); % USO DEL METODO STRREP PARA REMPLAZAR SIGNOS
                                         Key = strtrim(Key); %LIMPIAR ESPACIOS AL INICIO Y AL FINAL
                                         a =  strlength(app.Tc); % VARIABLE PARA ALMACENAR EL TAMAÑO DEL TEXTO PLANO
                                         if a==0
                                            app.TextArea.Value="No hay texto para descifrar";
                                            return
                                         end
                                         b= strlength(Key);%VARIABLE PARA ALMACENAR EL TAMAÑO DE LA LLAVE
                                         if b==0
                                             app.TextArea.Value="La llave no puede estar vacia";
                                             return
                                         end
                                        count=1;
                                    count2=1;
                                    temp2="";
                                    j=a;
                                    if mod(a,2) == 1
                                        j= a+1;
                                        a=a+1;
                                        j= j/2;
                                    else
                                        j= j/2;
                                    end
                                    while count < a
                                        app.temp = strcat(app.temp, extract(app.Tc,count));
                                         if count+1 > a
                                             
                                         else
                                             temp2 = strcat(temp2, extract(app.Tc,count+1));
                                         end
                                             app.temp = strcat(app.temp,temp2);
                                             count= count+2;
                                         app.Normalize(count2)=app.temp;
                                         app.temp="";
                                         temp2="";
                                         if count2 <j
                                             count2=count2+1;
                                         end
                                    end

                                    for i = 1:b%FOR PARA OBTENER LAS POCISIONES EN EL ALFABETO DE LA LLAVE
                                        app.temp = strcat(app.temp, extract(Key,i)); % SUSTRACCION DE CARACTERES DE LA LLAVE POR POSICION
                                        app.Newdiccionario(i)=app.temp;%SE AGREGA AL VECTOR LA POSCISION DEL CARACTER
                                        app.temp="";%LIMPIAR VARIABLE
                                   end% FIN DEL FOR
                                    for i = 1:length(app.plaFairDic)%FOR PARA OBTENER LAS POCISIONES EN EL ALFABETO DE LA LLAVE
                                        app.Newdiccionario(i+b)=app.plaFairDic(i);
                                    end% FIN DEL FOR
                                        app.FinalKey= unique(app.Newdiccionario,'stable');
                                        app.MatixPlay = reshape(app.FinalKey,5,5);   
                                        app.MatixPlay = app.MatixPlay.';

                                        for i=1:length(app.Normalize)
                                            var1= app.Normalize(i);
                                            app.M1=strcat(app.M1,extract(var1,1));
                                            app.M2=strcat(app.M2,extract(var1,2));
                                            [numRows,numCols] = size(app.MatixPlay);
                                            [row,col]= find(app.MatixPlay==app.M1);
                                            [row2,col2]= find(app.MatixPlay==app.M2); 
                                            if row == row2
                                                if col - 1 < 1
                                                    app.C1 =app.MatixPlay(row,numCols);
                                                    app.Tp = strcat( app.Tp,app.C1);
                                                else
                                                    app.C1 =app.MatixPlay(row,col - 1);
                                                    app.Tp = strcat( app.Tp,app.C1);
                                                end
                                                if col2 - 1 < 1
                                                    app.C2 =app.MatixPlay(row,numCols);
                                                    app.Tp = strcat( app.Tp,app.C2);
                                                else
                                                    app.C2 =app.MatixPlay(row,col2 - 1);
                                                    app.Tp = strcat( app.Tp,app.C2);
                                                end
                                            elseif col == col2
                                                if row - 1 < 1
                                                    app.C1 =app.MatixPlay(numRows,col);
                                                    app.Tp = strcat( app.Tp,app.C1);
                                                else
                                                    app.C1 =app.MatixPlay(row - 1,col);
                                                    app.Tp = strcat( app.Tp,app.C1);
                                                end
                                                if row2 - 1 < 1
                                                    app.C2 =app.MatixPlay(numRows,col2);
                                                    app.Tp = strcat( app.Tp,app.C2);
                                                else
                                                    app.C2 =app.MatixPlay(row2 - 1,col2);
                                                    app.Tp = strcat( app.Tp,app.C2);
                                                end
                                            elseif col ~= col2 && row ~= row2
                                                  incol=col2;
                                                  incol2=col;
                                                  app.C1 =app.MatixPlay(row,incol);
                                                  app.C2 =app.MatixPlay(row2,incol2);
                                                  app.Tp = strcat( app.Tp,app.C1);
                                                  app.Tp = strcat( app.Tp,app.C2);
                                            end
                                            var1="";
                                            app.M1="";
                                            app.M2="";
                                            app.C1="";
                                            app.C2="";
                                        end
                                        %app.TextArea.Value=app.Tp;% SE IMPRIME EL TEXTO CIFRADO
%                                             for i= 1:length(app.frecuencyTC)%Ciclo for para sacar las frecuencias de los caracteres
%                                                     b = app.frecuencyTC(i);%Igualando b al valor actual de app.frecuencyTC(i)
%                                                     c= b/a;%Dividiendo el valor actual entre el tamaño del texto 
%                                                     app.frecuencyTP(i)=c*100;%Multiplicando  app.frecuencyTC(i) por 100
%                                             end% FIN DEL FOR
%                                             
%                                             alfabet2 = categorical({'a' 'b' 'c' 'd' 'e' 'f' 'g' 'h' 'i' 'j' 'k' 'l' 'm' 'n' 'ñ' 'o' 'p' 'q' 'r' 's' 't' 'u' 'v' 'w' 'x' 'y' 'z'});%Vector del alfabeto para el histograma
%                                             c2=[app.frecuency;app.frecuencyTP;app.frecuency2];%VMatriz de las frecuencias
%                                             bar(app.axes1,alfabet2,c2);%Funcion para crear el histograma
%                                             legend(app.axes1,{'RAE';'Texto descifrado';'Ingles'});%Legendas para el histograma
%                                             title(app.axes1,"Histograma de Frecuencias texto descifrado");%Titulo para el histograma
%                                             for i= 1:length(app.frecuencyTP)%Ciclo for par limpiar el vector de frecuencia
%                                                 app.frecuencyTP(i)=0;%Igualando a cero los valores
%                                             end% FIN DEL FOR
                                        for i=1:strlength(app.Tp)
                                            app.temp=strcat(app.temp,extract(app.Tp,i));
                                            index= find(app.MatixPlay == app.temp);
                                            app.frecuencyTCPlay(index)= app.frecuencyTCPlay(index) + 1;
                                            app.temp="";
                                        end
                                        for i= 1:length(app.frecuencyTCPlay)%Ciclo for para sacar las frecuencias de los caracteres
                                            b = app.frecuencyTCPlay(i);%Igualando b al valor actual de app.frecuencyTP(i)
                                            c= b/a;%Dividiendo el valor actual entre el tamaño del texto 
                                            app.frecuencyTPPlay(i)=c*100;%Multiplicando  app.frecuencyTC(i) por 100
                                        end% FIN DEL FOR
                                    app.TextArea.Value=app.Tp;% SE IMPRIME EL TEXTO CIFRADO
                                    app.Tp = ""; %LIMPIAR VARIABLE
                                    alfabet2 = categorical(app.FinalKey);%Vector del alfabeto para el histograma
                                    %c2=[app.frecuency;app.frecuencyTC;app.frecuency2];%VMatriz de las frecuencias
                                    bar(app.axes1,alfabet2,app.frecuencyTPPlay);%Funcion para crear el histograma
                                    legend(app.axes1,'Texto cifrado');%Legendas para el histograma
                                    title(app.axes1,"Histograma de Frecuencias texto cifrado");%Titulo para el histograma
                                    for i= 1:length(app.frecuencyTPPlay)%Ciclo for par limpiar el vector de frecuencia
                                        app.frecuencyTPPlay(i)=0;%Igualando a cero los valores
                                    end% FIN DEL FOR
                            end% FIN DEL IF
                end% FIN DEL SWITCH
        end

        % Value changed function: popupmenu1
        function popupmenu1ValueChanged(app, event)
            if app.popupmenu1.Value == "Cesar"%VALIDACION PARA VER SI ESTA SELECCIONADO EL CIFRADO CESAR 
               app.EditField_3.Visible="off";%SI  ES EL CIFRADO CESAR EL TEXTBOX DE TEXTO ESTA DESHABILITADO
               app.EditField_2.Visible="off";%SI  ES EL CIFRADO CESAR EL TEXTBOX DE TEXTO ESTA DESHABILITADO
               app.EditField.Visible="on";%SI  ES EL CIFRADO CESAR EL TEXTBOX NUMERICO ESTA HABILITADO
            elseif app.popupmenu1.Value == "Vigener"
                app.EditField_3.Visible="off";%SI  ES EL CIFRADO CESAR EL TEXTBOX DE TEXTO ESTA DESHABILITADO
               app.EditField_2.Visible="on";%SI NO ES EL CIFRADO CESAR EL TEXTBOX DE TEXTO ESTA HABILITADO
               app.EditField.Visible="off";%SI NO ES EL CIFRADO CESAR EL TEXTBOX NUMERICO ESTA DESHABILITADO
            else %%Playfair
                app.EditField_3.Visible="on";%SI  ES EL CIFRADO CESAR EL TEXTBOX DE TEXTO ESTA DESHABILITADO
                 app.EditField_2.Visible="on";%SI NO ES EL CIFRADO CESAR EL TEXTBOX DE TEXTO ESTA HABILITADO
                 app.EditField.Visible="off";%SI NO ES EL CIFRADO CESAR EL TEXTBOX NUMERICO ESTA DESHABILITADO
            end% FIN DEL IF
        end

        % Button pushed function: HistogramaESINButton
        function HistogramaESINButtonPushed(app, event)
                
                alfabet2 = categorical({'a' 'b' 'c' 'd' 'e' 'f' 'g' 'h' 'i' 'j' 'k' 'l' 'm' 'n' 'ñ' 'o' 'p' 'q' 'r' 's' 't' 'u' 'v' 'w' 'x' 'y' 'z'});%Vector del alfabeto para el histograma
                c1=[app.frecuency;app.frecuency2];%VMatriz de las frecuencias
                bar(app.axes1,alfabet2,c1);%Funcion para crear el histograma
                legend(app.axes1,{'Español';"Ingles"});%Legendas para el histograma
                title(app.axes1,"Histograma de Frecuencias Español e Ingles");%Titulo para el histograma
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            app.TextArea.Value="";
            cla(app.axes1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUICifrados';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Cifrados Clasicos';
            app.uipanel1.Tag = 'uipanel1';
            app.axes1 = uiaxes(app.uipanel1);
            app.axes1.Tag = 'axes1';
            app.pushbutton1 = uibutton(app.uipanel1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Ejecutar';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Insertar Texto';
            app.popupmenu1 = uidropdown(app.uipanel1);
            app.popupmenu1.Items = {'Cesar', 'Vigenere', 'Playfair'};
            app.popupmenu1.ValueChangedFcn = createCallbackFcn(app, @popupmenu1ValueChanged, true);
            app.popupmenu1.Tag = 'popupmenu1';
            app.popupmenu1.Value = 'Cesar';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Insertar llave';
            app.TextArea = uitextarea(app.uipanel1);
            app.TipodeoperacionSwitchLabel = uilabel(app.uipanel1);
            app.TipodeoperacionSwitchLabel.Text = 'Tipo de operacion';
            app.TipodeoperacionSwitch = uiswitch(app.uipanel1, 'slider');
            app.TipodeoperacionSwitch.Items = {'Cifrado', 'Descifrado'};
            app.TipodeoperacionSwitch.Value = 'Cifrado';
            app.EditField = uieditfield(app.uipanel1, 'numeric');
            app.EditField.RoundFractionalValues = 'on';
            app.EditField.Value = 2;
            app.HistogramaESINButton = uibutton(app.uipanel1, 'push');
            app.HistogramaESINButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramaESINButtonPushed, true);
            app.HistogramaESINButton.Text = 'Histograma ES/IN';
            app.EditField_2 = uieditfield(app.uipanel1, 'text');
            app.EditField_2.Visible = 'off';
            app.EditField_2.Placeholder = 'Ingresa tu llave';
            app.LimpiarButton = uibutton(app.uipanel1, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar ';
            app.EditField_3 = uieditfield(app.uipanel1, 'text');
            app.EditField_3.Visible = 'off';
            app.EditField_3.Placeholder = 'Valor neutro';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUICifrados_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GUICifrados_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end